classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimulationMenu                  matlab.ui.container.Menu
        NewsimulationMenu               matlab.ui.container.Menu
        SavesimulationMenu              matlab.ui.container.Menu
        LoadsimulationMenu              matlab.ui.container.Menu
        EditcurrentsimulationMenu       matlab.ui.container.Menu
        SolutionMenu                    matlab.ui.container.Menu
        StartnewsolverMenu              matlab.ui.container.Menu
        SavesolutionMenu                matlab.ui.container.Menu
        LoadsolutionMenu                matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        ConfigurationTab                matlab.ui.container.Tab
        ConfigGrid                      matlab.ui.container.GridLayout
        TextArea                        matlab.ui.control.TextArea
        VehiclesPanel                   matlab.ui.container.Panel
        HighlightselectedVehicleButton  matlab.ui.control.Button
        DeleteselectedDepotsButton      matlab.ui.control.Button
        CapacitySpinner                 matlab.ui.control.Spinner
        CapacitySpinnerLabel            matlab.ui.control.Label
        AddDepotButton                  matlab.ui.control.Button
        RequestsPanel                   matlab.ui.container.Panel
        HighlightselectedRequestButton  matlab.ui.control.Button
        DeleteselectedRequestButton     matlab.ui.control.Button
        DropoffendEditField             matlab.ui.control.EditField
        TimewindowEditField             matlab.ui.control.EditField
        TimewindowEditFieldLabel        matlab.ui.control.Label
        AddnewButton                    matlab.ui.control.Button
        GridLayout7                     matlab.ui.container.GridLayout
        EdgetimeSpinner                 matlab.ui.control.Spinner
        EdgetimeSpinnerLabel            matlab.ui.control.Label
        MapsidelengthSpinner            matlab.ui.control.Spinner
        MapsidelengthSpinnerLabel       matlab.ui.control.Label
        TableVehicle                    matlab.ui.control.Table
        TableRequest                    matlab.ui.control.Table
        VisualizationTab                matlab.ui.container.Tab
        GridLayoutVisualize             matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        VehiclesLabel                   matlab.ui.control.Label
        VehiclesDropDown                matlab.ui.control.DropDown
        TextArea_2                      matlab.ui.control.TextArea
        RouteTable                      matlab.ui.control.Table
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        % Subwindows
        warnDialog; %reference to a warndialog window
        solutionWindow;
        
        % UI state data
        mainUiState; % 1 - configure simulation, 2 - show simulation
        uiState; %State of the UI. 1 - basic, 2 - add request, 3 - add depot
        visualizeStatePlayPause; %1 - play-pause mode in visualize state; 2 - step-by-step mode
        play; % 1-playing 0-paused
        
        requestsExist; % indicates that the simulation contains requests
        vehiclesExist; % indicates that the simulation contains vehicles
        routesExist; %indicates that the solution is loaded
        pickupAdded; % indicate that the request already has a pickup location
        pickUpStartEdited;
        dropOffEndEdited;
        highlightedRequestNodes; % The id of the request that should be highlighted
        highlightedVehicleNodes; % The id of the vehicle that should be highlighted
                 
        % Image parameters
        img; % an underlying layer under the top layers
        imgTop; % reference to the image that is displayed in the main Image component
        resolution; % global parameter
        padding; % global parameter
        nodeRadius; % radius of all the nodes in the images
        
        % Simulation data
        graph; 
        coordsNodesImg;
        coordsArcsImg;
        vehicles; % array of vehicles
        requests; % array of requests
        routes; % (cell)array of vehicle routes
    end
    
    methods (Access = public)
        function returnFromSolver(app, resultIn, routesIn, requestsIn, vehiclesIn)
            % returnFromSolverSelect
            % this public function is used for passing data back to the
            % main app from the solver selector window
            
            if(resultIn == 1)
                %Not changing the UI state - staying in configuration mode
                app.mainUiState = 1;
                %Change to the correct tab
                app.TabGroup.SelectedTab = app.ConfigurationTab;
            elseif(resultIn == 2)
                %Switching to visualization mode
                app.mainUiState = 2;
                app.routes = routesIn;
                app.vehicles = vehiclesIn;
                %app.requests = requestsIn;
                % Change to the correct tab
                app.TabGroup.SelectedTab = app.VisualizationTab;
                % Update the routes dropDown
                app.updateRouteDropDown();
                % Update the routeTable
                app.updateRouteTable();
                % Update the base layer of the image
                app.updateImg();
                app.updateImgTopLayer(-1);
            end
        end
    end
    
    methods (Access = private)      
         
        function updateImg(app)
            %1. Create empty white image
            app.img = uint8(ones(app.resolution,app.resolution,3)*255);
            
            %2. Draw all lines
            for i = 1:app.graph.nArcs
                app.img = drawLine(app.img, ...
                                  app.coordsArcsImg(i,1), ...
                                  app.coordsArcsImg(i,2), ...
                                  app.coordsArcsImg(i,3), ...
                                  app.coordsArcsImg(i,4), ...
                                  [0,0,0], ...
                                  3);
            end
            
                        
            %3. Draw highlighted lines
            if(app.mainUiState == 2)
                routeName = app.VehiclesDropDown.Value;
                vehicleId = str2double(replace(routeName,"Vehicle ",""));
                route = app.getRouteById(vehicleId);

                routeAllNodes = route{2};
                routeLength = size(routeAllNodes,2);
                lastNode = routeAllNodes(1);
                for i = 2:routeLength
                    lastNodeX = app.coordsNodesImg(lastNode,2);
                    lastNodeY = app.coordsNodesImg(lastNode,1);
                    thisNodeX = app.coordsNodesImg(routeAllNodes(i),2);
                    thisNodeY = app.coordsNodesImg(routeAllNodes(i),1);
                    lastNode = routeAllNodes(i);
                    
                    app.img = drawLine(app.img, ...
                                  lastNodeY, ...
                                  lastNodeX, ...
                                  thisNodeY, ...
                                  thisNodeX, ...
                                  [255,200,0], ...
                                  7);
                                        
                end
            end
            
            
            %3. Draw all nodes
            for i = 1:app.graph.nNodes
                app.img = drawDot(app.img, ...
                                  app.coordsNodesImg(i,1), ...
                                  app.coordsNodesImg(i,2), ...
                                  app.nodeRadius, ...
                                  [0,0,0], ...
                                  1, ...
                                  [0,0,0], ...
                                  -1);
            end
            
            %Update the image
            app.Image.ImageSource = app.img;
        end
        
        function updateImgTopLayer(app, newPickupNodeId)
            % Add a top layer to the img with the request and depot nodes
            % params:
            %   - newPickup: the nodeID of the pickup node of the new
            %   request
            
            [pickUps, dropOffs, depotsLocal] = getRequestDepotnodes(app);
            
            %Check if the newPickupNodeId parameter is valid
            if(newPickupNodeId > 0 && newPickupNodeId <= app.graph.nNodes)
                disp("Valid pickup point")
                pickUps = [pickUps; newPickupNodeId];
            end
            
            % Copy the base img to the top layer
            app.imgTop = app.img;
            
            for i = 1:app.graph.nNodes
                % Draw highlighted depots
                if(ismember(i, app.highlightedVehicleNodes))
                    %Border
                    app.imgTop =  drawDepotRect(app.imgTop, ...
                        app.coordsNodesImg(i,1)-floor(app.nodeRadius*2+2), ...
                        app.coordsNodesImg(i,2)-floor(app.nodeRadius*2+2), ...
                        app.nodeRadius*4+4, app.nodeRadius*4+4, [0,0,0]);
                    %Fill
                    app.imgTop =  drawDepotRect(app.imgTop, ...
                        app.coordsNodesImg(i,1)-floor(app.nodeRadius*2), ...
                        app.coordsNodesImg(i,2)-floor(app.nodeRadius*2), ...
                        app.nodeRadius*4, app.nodeRadius*4, [255,255,0]);                    
                end
                
                % Draw depots       
                if(ismember(i, depotsLocal))
                    app.imgTop =  drawDepotRect(app.imgTop, ...
                        app.coordsNodesImg(i,1)-floor(app.nodeRadius*1.5), ...
                        app.coordsNodesImg(i,2)-floor(app.nodeRadius*1.5), ...
                        app.nodeRadius*3, app.nodeRadius*3, [0,0,0]);
                end
                
                % Draw highlighted requests
                if(ismember(i, app.highlightedRequestNodes))
                    app.imgTop = drawDot(app.imgTop, ...
                              app.coordsNodesImg(i,1), ...
                              app.coordsNodesImg(i,2), ...
                              floor(app.nodeRadius*1.5), ...
                              [0,0,0], ...
                              2, ...
                              [255,255,0], ...
                              -1);
                end

                % Draw nodes that are both pickup and dropoff places
                % half green - half red
                if(ismember(i, pickUps) && ismember(i,dropOffs))
                    app.imgTop = drawDot(app.imgTop, ...
                                  app.coordsNodesImg(i,1), ...
                                  app.coordsNodesImg(i,2), ...
                                  app.nodeRadius, ...
                                  [0,0,0], ...
                                  1, ...
                                  [0,128,0], ...
                                  [255,0,0]);
                else
                    % Draw pickups - green
                    if(ismember(i, pickUps))
                        app.imgTop = drawDot(app.imgTop, ...
                                      app.coordsNodesImg(i,1), ...
                                      app.coordsNodesImg(i,2), ...
                                      app.nodeRadius, ...
                                      [0,0,0], ...
                                      1, ...
                                      [0,128,0], ...
                                      -1);
                    end
                    
                    % Draw dropoffs - red
                    if(ismember(i,dropOffs))
                        app.imgTop = drawDot(app.imgTop, ...
                                      app.coordsNodesImg(i,1), ...
                                      app.coordsNodesImg(i,2), ...
                                      app.nodeRadius, ...
                                      [0,0,0], ...
                                      1, ...
                                      [255,0,0], ...
                                      -1);
                    end
                end
            end
            
            %Update the image
            app.Image.ImageSource = app.imgTop;
        end
        
        function convertToImgCoords(app)
            max_x = max(app.graph.nodeCoords(:,1));
            max_y = max(app.graph.nodeCoords(:,1));
            
            % The padding
            offset = app.padding;
            % Scaling value along the x axis
            multip_x = (app.resolution - 2 * app.padding)/max_x;
            % Scaling value along the y axis
            multip_y = (app.resolution - 2 * app.padding)/max_y;
            
            %Coordinates of nodes
            app.coordsNodesImg = zeros(app.graph.nNodes,2);
            for i = 1:app.graph.nNodes
                app.coordsNodesImg(i,1) = round(offset + multip_x * app.graph.nodeCoords(i,1));
                app.coordsNodesImg(i,2) = round(offset + multip_y * app.graph.nodeCoords(i,2));
            end
            
            % Init the global radius parameter
            app.nodeRadius = floor((multip_x+multip_y)/20);            
            if(2 * app.nodeRadius > app.padding)
               app.nodeRadius = app.padding / 2 - 2;
            end
            
            %Start and end coordinates of arcs
            app.coordsArcsImg = zeros(app.graph.nNodes,4);
            for i = 1:app.graph.nArcs
                app.coordsArcsImg(i,1) = round(offset + multip_x * app.graph.arcCoords(i,1));
                app.coordsArcsImg(i,2) = round(offset + multip_y * app.graph.arcCoords(i,2));
                app.coordsArcsImg(i,3) = round(offset + multip_x * app.graph.arcCoords(i,3));
                app.coordsArcsImg(i,4) = round(offset + multip_y * app.graph.arcCoords(i,4));
            end
        end
        
        function handleImgClick(app, coordX, coordY)
            %Declare local persistent variable
            persistent newRequest
            
            switch(app.uiState)
                case 1 % STATE_BASIC
                    app.updateTextArea(app.findNodeByCoord(coordX, coordY));
                    
                case 2 % STATE_ADD_REQUEST_PICKUP
                    nodeId = app.findNodeByCoord(coordX, coordY);
                    %Check if nodeId is valid
                    if(nodeId ~= -1)
                        fprintf("Pickup node is %d\n",nodeId)
                        % Create an empty request
                        newRequest = darpRequest;
                        %Add pickup node
                        newRequest.pickUpNodeId = nodeId;
                        if(~app.requestsExist)
                            newRequest.requestId = 1;
                        else
                            newRequest.requestId = app.getMaxRequestId()+1;
                        end
                        % Update the top layer of the img
                        app.updateImgTopLayer(nodeId)
                        % Switch to STATE_ADD_REQUEST_DROPOFF
                        app.uiState = 3;
                        app.TextArea.Value = "Select dropoff node!";
                    end

                case 3 % STATE_ADD_REQUEST_DROPOFF
                    nodeId = app.findNodeByCoord(coordX, coordY);
                    if(nodeId == -1)
                        %Not a node
                    elseif(newRequest.pickUpNodeId == nodeId)
                        app.TextArea.Value = "Select dropoff node, other than the pickup node!";
                    else
                        fprintf("Dropoff node is %d\n",nodeId)
                        % Add dropoff nodeid
                        newRequest.dropOffNodeId = nodeId;
                        % Get pickup and dropoff times
                        newRequest.pickUpTimeEarliest = convertTimeToMin(app.TimewindowEditField.Value);
                        newRequest.dropOffTimeLatest = convertTimeToMin(app.DropoffendEditField.Value);
                        % Add request to all stored requests
                        if(~app.requestsExist)
                            app.requests = newRequest;
                            app.requestsExist = true;
                        else
                            app.requests = [app.requests; newRequest];
                        end
                        % Update the top layer of the img
                        app.updateImgTopLayer(-1)
                        % Switch to STATE_BASIC
                        app.uiState = 1;
                        app.TextArea.Value = "App is ready.";
                        app.RequestsPanel.BackgroundColor = [0.94,0.94,0.94]; %orginal grey
                        app.RequestsPanel.FontWeight = 'normal';
                        app.AddnewButton.Text = "Add new";
                        % Enable the editfields
                        app.TimewindowEditField.Editable = "on";
                        app.DropoffendEditField.Editable = "on";
                        % Update the table with the requests
                        app.updateRequestsTable();
                    end
                case 4 % STATE_ADD_DEPOT
                    nodeId = app.findNodeByCoord(coordX, coordY);
                    if(nodeId ~= -1)
                        newVehicle = darpVehicle;
                        newVehicle.depotNodeId = nodeId;
                        newVehicle.capacity = app.CapacitySpinner.Value;
                        if(~app.vehiclesExist)
                            newVehicle.vehicleId = 1;
                            app.vehicles = newVehicle;
                            app.vehiclesExist = true;
                        else
                            newVehicle.vehicleId = app.getMaxVehicleId() + 1;
                            app.vehicles = [app.vehicles; newVehicle];
                        end
                        % Update the top layer of the img
                        app.updateImgTopLayer(-1)
                        % Enable the spinner
                        app.CapacitySpinner.Enable = "on";
                        % Modify appearance
                        app.VehiclesPanel.BackgroundColor = [0.94,0.94,0.94]; %orginal grey
                        app.AddDepotButton.Text = "Add new";
                        % Switch to  STATE_BASIC
                        app.uiState = 1; 
                        app.TextArea.Value = "App is ready.";
                        % Update the vehicles table
                        updateDepotsTable(app)
                    end
           end
        end
        
        function id = findNodeByCoord(app, coordXIn, coordYIn)
            % Returns the id of the node based on the coordinates
            id = -1;
            for i = 1:app.graph.nNodes
                if(app.checkClickInNode(i,coordXIn, coordYIn))
                    id = i;
                end
            end
        end
        
        function isIn = checkClickInNode(app, nodeId, coordXIn, coordYIn)
            xNodeImg = app.coordsNodesImg(nodeId, 1);
            yNodeImg = app.coordsNodesImg(nodeId, 2);
            if((xNodeImg - coordXIn)^2 + (yNodeImg - coordYIn)^2 <= app.nodeRadius^2)
                isIn = true;
            else
                isIn = false;
            end
        end
    
        function [pickUps, dropOffs, depots] = getRequestDepotnodes(app)
            % Iterate over the array of requests and depots. Returns the
            % nodeIds of pickup, dropoff and depot nodes           
            
            if(app.requestsExist)
                pickUpsTemp = zeros(size(app.requests,1),1);
                dropOffsTemp = zeros(size(app.requests,1),1);
                % Iterate over all requests
                for i = 1:size(app.requests,1)
                    pickUpsTemp(i) = app.requests(i).pickUpNodeId;
                    dropOffsTemp(i) = app.requests(i).dropOffNodeId;
                end
                pickUps = pickUpsTemp;
                dropOffs = dropOffsTemp;
            else
                pickUps = [0];
                dropOffs = [0];
            end
            
            if(app.vehiclesExist)
                depotsTemp = zeros(size(app.vehicles,1),1);
                %Iterate over all vehicles
                for i = 1:size(app.vehicles,1)
                    depotsTemp(i) = app.vehicles(i).depotNodeId;
                end
                depots = unique(depotsTemp);
            else
                depots = [0];
            end
        end
    
        function updateRequestsTable(app)
            %
            if(app.requestsExist)
                ids = zeros(size(app.requests,1),1);
                pickUpNodes = zeros(size(app.requests,1),1);
                pickUpTimes = string(zeros(size(app.requests,1),1));
                dropOffNodes = zeros(size(app.requests,1),1);
                dropOffTimes = string(zeros(size(app.requests,1),1));
                shows = zeros(size(app.requests,1),1);
                for i = 1:size(app.requests)
                    thisRequest = app.requests(i);
                    %Collect the data of the request
                    ids(i) = thisRequest.requestId;
                    pickUpNodes(i) = thisRequest.pickUpNodeId;
                    pickUpTimes(i) = convertMinToTime(thisRequest.pickUpTimeEarliest);
                    dropOffNodes(i) = thisRequest.dropOffNodeId;
                    dropOffTimes(i) = convertMinToTime(thisRequest.dropOffTimeLatest);
                    shows(i) = false;
                end
                dataTable = table(shows, ids, pickUpNodes, pickUpTimes, dropOffNodes, dropOffTimes);
                app.TableRequest.Data = dataTable;
            else
                app.TableRequest.Data = {};
            end
        end
        
        function updateDepotsTable(app)
            %
            if(app.vehiclesExist)
                ids = zeros(size(app.vehicles,1),1);
                depotNodes = zeros(size(app.vehicles,1),1);
                capacities = zeros(size(app.vehicles,1),1);
                shows = zeros(size(app.vehicles,1),1);
                for i = 1:size(app.vehicles)
                    thisVehicle = app.vehicles(i);
                    %Collect the data of the request
                    ids(i) = thisVehicle.vehicleId;
                    depotNodes(i) = thisVehicle.depotNodeId;
                    capacities(i) = thisVehicle.capacity;
                    shows(i) = false;
                end
                dataTable = table(shows, ids, depotNodes, capacities);
                app.TableVehicle.Data = dataTable;
            else
                app.TableVehicle.Data = {};
            end
        end
        
        function saveSimulation(app)
            if(~app.requestsExist && ~app.vehiclesExist)
                app.warnDialog = warndlg("Nothing to save here.");
            else
                %Open a UI dialog to select the path
                filename = "darpSimulation-" + datestr(now, 'yyyy-mm-dd-HH-MM') + ".mat";
                [file,path] = uiputfile('*.mat', "Save simulation", filename);
                if isequal(file,0) || isequal(path,0)
                    %Nothing
                else
                    %Save the simulation data
                    sideLength_ = app.graph.sideLength;
                    edgeTime_ = app.EdgetimeSpinner.Value;
                    requests_ = app.requests;
                    vehicles_ = app.vehicles;
                    save(fullfile(path,file),'sideLength_', 'edgeTime_','requests_', 'vehicles_');
                end     
            end
        end
        
        function status = loadSimulation(app)
            [file,path] = uigetfile('*.mat');
            loaded = load(fullfile(path,file));
            
            %Load graph and update value on spinner
            app.graph = darpSquareGridGraph(loaded.sideLength_);
            app.MapsidelengthSpinner.Value = loaded.sideLength_;
            
            %Load side length and update the value on spinner
            app.EdgetimeSpinner.Value = loaded.edgeTime_;
            
            % Check if requests were saved
            if(~isempty(loaded.requests_))
                app.requests = loaded.requests_;
                app.requestsExist = true;
            else
                app.requestsExist = false;
            end
            
            % Check if vehicles were saved
            if(~isempty(loaded.vehicles_))
                app.vehicles = loaded.vehicles_;
                app.vehiclesExist = true;
            else
                app.vehiclesExist = false;
            end
                
            %Convert the graph coordinates to img coordinates
            app.convertToImgCoords();
            
            % Initialize the status flags
            app.pickUpStartEdited = false;
            app.dropOffEndEdited = false;
            
            %clear highlights
            app.highlightedRequestNodes = [];
            app.highlightedVehicleNodes = [];

            %Update the main image
            app.updateImg();
            app.updateImgTopLayer(-1);
            
            %Update the tables
            app.updateRequestsTable();
            app.updateDepotsTable();
            
            %Set UI state
            app.mainUiState = 1;
            app.uiState = 1;
            app.routesExist = 1;
            app.TextArea.Value = "App is ready.";
            app.TabGroup.SelectedTab = app.ConfigurationTab;
        end
        
        function newSimulation(app)
            % Delete the requests
            app.requests = 0;
            app.requestsExist = false;
            % Delete the vehicles
            app.vehicles = 0;
            app.vehiclesExist = false;
            % Delete routes
            app.routes = 0;
            app.routesExist = false;
            %Update the graph
            app.graph = darpSquareGridGraph(app.MapsidelengthSpinner.Value);
            %Update the image coordinates
            app.convertToImgCoords();
            %Update the main image
            app.updateImg();
            %Update the tables
            app.updateRequestsTable();
            app.updateDepotsTable();
            %
            app.mainUiState = 1;
            app.uiState = 1;
            app.TextArea.Value = "App is ready.";
            app.TabGroup.SelectedTab = app.ConfigurationTab;
        end
        
        function saveSolution(app)
            if(~app.routesExist)
                app.warnDialog = warndlg("Nothing to save here.");
            else
                %Open a UI dialog to select the path
                filename = "darpSimulationSolution-" + datestr(now, 'yyyy-mm-dd-HH-MM') + ".mat";
                [file,path] = uiputfile('*.mat', "Save simulation", filename);
                if isequal(file,0) || isequal(path,0)
                    %Nothing
                else
                    %Save the simulation data
                    sideLength_ = app.graph.sideLength;
                    requests_ = app.requests;
                    vehicles_ = app.vehicles;
                    routes_ = app.routes;
                    edgeTime_ = app.EdgetimeSpinner.Value;
                    save(fullfile(path,file),'sideLength_', 'edgeTime_', 'requests_', 'vehicles_', 'routes_');
                end     
            end
        end
        
        function status = loadSolution(app)
            status = 0;
            try
                [file,path] = uigetfile('*.mat');
                loaded = load(fullfile(path,file));
                
                %Load graph and update value on spinner
                app.graph = darpSquareGridGraph(loaded.sideLength_);
                app.MapsidelengthSpinner.Value = loaded.sideLength_;
                
                %Load side length and update the value on spinner
                app.EdgetimeSpinner.Value = loaded.edgeTime_;
                
                % Check if requests were saved
                if(~isempty(loaded.requests_))
                    app.requests = loaded.requests_;
                    app.requestsExist = true;
                else
                    app.requestsExist = false;
                end
                
                % Check if vehicles were saved
                if(~isempty(loaded.vehicles_))
                    app.vehicles = loaded.vehicles_;
                    app.vehiclesExist = true;
                else
                    app.vehiclesExist = false;
                end
                
                % Check if solution was saved
                if(~isempty(loaded.routes_))
                    app.routes = loaded.routes_;
                    app.routesExist = true;
                else
                    error("File doesn't contain solution")
                end
                
                %Convert the graph coordinates to img coordinates
                app.convertToImgCoords();
                
                % Initialize the status flags
                app.pickUpStartEdited = false;
                app.dropOffEndEdited = false;                
            catch err
                %Error
                status = 1;
                fprintf("An error occured: %d\n", err.message);
            end
        end
        
        function maxId = getMaxRequestId(app)
            maxId = 0;
            for i = 1:size(app.requests)
                if(app.requests(i).requestId > maxId)
                    maxId = app.requests(i).requestId;
                end
            end
        end
        
        function maxId = getMaxVehicleId(app)
            maxId = 0;
            for i = 1:size(app.vehicles)
                if(app.vehicles(i).vehicleId > maxId)
                    maxId = app.vehicles(i).vehicleId;
                end
            end
        end
        
        function request = getRequestById(app, id)
            for i = 1:size(app.requests,1)
                if(app.requests(i).requestId == id)
                    request = app.requests(i);
                    return;
                end
            end
            request = 1;
        end
        
        function vehicle = getVehicleById(app,id)
            for i = 1:size(app.vehicles,1)
                if(app.vehicles(i).vehicleId == id)
                    vehicle = app.vehicles(i);
                    return;
                end
            end
            vehicle = 1;
        end
        
        function updateTextArea(app, nodeId)
            if(nodeId == -1)
                app.TextArea.Value = "App is ready.";
            else
                pickupRequestIds = [];
                pickupRequestTimes = [];
                dropOffRequestIds = [];
                dropOffRequestTimes = [];
                
                vehicleIds = [];
                vehicleCapacities = [];
                            
                % Iterate over requests
                numPickups = 0;
                numDropOffs = 0;
                if(app.requestsExist)
                    for i = 1:size(app.requests,1)
                        if(app.requests(i).pickUpNodeId == nodeId)
                            numPickups = numPickups+1;
                            pickupRequestIds = [pickupRequestIds; app.requests(i).requestId];
                            pickupRequestTimes = [pickupRequestTimes; app.requests(i).pickUpTimeEarliest];
                        elseif(app.requests(i).dropOffNodeId == nodeId)
                            numDropOffs = numDropOffs + 1;
                            dropOffRequestIds = [dropOffRequestIds; app.requests(i).requestId];
                            dropOffRequestTimes = [dropOffRequestTimes; app.requests(i).dropOffTimeLatest];
                        end
                    end                    
                end

                % Print all vehicles
                numVehicles = 0;
                if(app.vehiclesExist)
                    % Iterate over vehicles
                    for i = 1:size(app.vehicles,1)
                        if(app.vehicles(i).depotNodeId == nodeId)
                            numVehicles = numVehicles + 1;
                            vehicleIds = [vehicleIds; app.vehicles(i).vehicleId];
                            vehicleCapacities = [vehicleCapacities; app.vehicles(i).capacity];
                        end
                    end
                end
                
                
                %Update the text
                text = sprintf("Node %d\n",nodeId);
                if(numPickups>0 && numDropOffs>0)
                    text = sprintf("%s____________________________\nPickups\t\t\t\tDropoffs\n",text);
                    %Equal columns
                    if(numPickups == numDropOffs)
                        for i = 1:numPickups
                            if(pickupRequestIds(i)<10)
                                text = sprintf("%s%2d - %s\t\t\t\t%2d - %s\n",text, ...
                                                pickupRequestIds(i), ...
                                                convertMinToTime(pickupRequestTimes(i)), ...
                                                dropOffRequestIds(i), ...
                                                convertMinToTime(dropOffRequestTimes(i)));
                            else
                                text = sprintf("%s%2d - %s\t\t\t%2d - %s\n",text, ...
                                                pickupRequestIds(i), ...
                                                convertMinToTime(pickupRequestTimes(i)), ...
                                                dropOffRequestIds(i), ...
                                                convertMinToTime(dropOffRequestTimes(i)));
                            end
                        end
                    %Not equal columns - more pickups
                    elseif(numPickups > numDropOffs)
                        %Print the matching parts
                        for i = 1:numDropOffs
                            if(pickupRequestIds(i)<10)
                                text = sprintf("%s%2d - %s\t\t\t\t%2d - %s\n",text, ...
                                                pickupRequestIds(i), ...
                                                convertMinToTime(pickupRequestTimes(i)), ...
                                                dropOffRequestIds(i), ...
                                                convertMinToTime(dropOffRequestTimes(i)));
                            else
                                text = sprintf("%s%2d - %s\t\t\t%2d - %s\n",text, ...
                                                pickupRequestIds(i), ...
                                                convertMinToTime(pickupRequestTimes(i)), ...
                                                dropOffRequestIds(i), ...
                                                convertMinToTime(dropOffRequestTimes(i)));
                            end
                        end
                        
                        for i = numDropOffs:numPickups
                            text = sprintf("%s%2d - %s\n",text, ...
                                                pickupRequestIds(i), ...
                                                convertMinToTime(pickupRequestTimes(i)));
                        end
                    % more dropoffs
                    else
                        %Print the matching parts
                        for i = 1:numPickups
                            if(pickupRequestIds(i)<10)
                                text = sprintf("%s%2d - %s\t\t\t\t%2d - %s\n",text, ...
                                                pickupRequestIds(i), ...
                                                convertMinToTime(pickupRequestTimes(i)), ...
                                                dropOffRequestIds(i), ...
                                                convertMinToTime(dropOffRequestTimes(i)));
                            else
                                text = sprintf("%s%2d - %s\t\t\t%2d - %s\n",text, ...
                                                pickupRequestIds(i), ...
                                                convertMinToTime(pickupRequestTimes(i)), ...
                                                dropOffRequestIds(i), ...
                                                convertMinToTime(dropOffRequestTimes(i)));
                            end
                        end
                        
                        for i = numPickups:numDropOffs
                            text = sprintf("%s\t\t\t\t\t%2d - %s\n",text, ...
                                            dropOffRequestIds(i), ...
                                            convertMinToTime(dropOffRequestTimes(i)));
                        end
                    end
                elseif(numPickups>0 && numDropOffs==0)
                    text = sprintf("%s____________________________\nPickups\n",text);
                    for i = 1:numPickups
                        text = sprintf("%s%2d - %s\n",text, ...
                                        pickupRequestIds(i), ...
                                        convertMinToTime(pickupRequestTimes(i)));
                    end
                elseif(numPickups==0 && numDropOffs>0)
                    text = sprintf("%s____________________________\nDropoffs\n",text);
                    for i = 1:numDropOffs
                        text = sprintf("%s%2d - %s\n",text, ...
                                        dropOffRequestIds(i), ...
                                        convertMinToTime(dropOffRequestTimes(i)));
                    end
                end
                %Add the vehicles to the text
                if(numVehicles>0)
                    text = sprintf("%s____________________________\n",text);
                    text = sprintf("%sVehicles (cap.)\n",text);
                    for i = 1:numVehicles
                        text = sprintf("%s%d - %d\n",text,vehicleIds(i),vehicleCapacities(i));
                    end
                end
                app.TextArea.Value = text;
                app.TextArea_2.Value = text;
            end
        end
        
        function updateRouteDropDown(app)
            %Iterate over all routes
            vehicle_names = strings(size(app.routes,1),1);
            for i = 1:size(app.routes,1)
                vehicle_names(i) = sprintf("Vehicle %d", app.routes{i,1});
            end
            % Update vehicle names in dropdown
            app.VehiclesDropDown.Items = vehicle_names;
            app.VehiclesDropDown.Value = vehicle_names(1);
        end
        
        function updateRouteTable(app)
            app.RouteTable.ColumnFormat = {'char', 'numeric', 'numeric', 'char', 'char'};
            
            routeName = app.VehiclesDropDown.Value;
            vehicleId = str2double(replace(routeName,"Vehicle ",""));
            route = app.getRouteById(vehicleId);        
            vehicle = app.getVehicleById(vehicleId);
    
            routeTimeSteps = route{3};
            routePassengersAfterNode = route{4};
            routeAllNodes = route{2};
            routeLength = size(routeTimeSteps,2);
            
            if(iscell(route))
                timeSteps = strings(routeLength,1);
                from = zeros(routeLength,1);
                to = zeros(routeLength,1);
                passengersIn = cell(routeLength,1);
                passengersOut = cell(routeLength,1);
                
                lastNode = vehicle.depotNodeId;
                lastPassengers = [];
                for i = 1:routeLength
                    timeSteps(i) = convertMinToTime(routeTimeSteps(i));
                    from(i) = lastNode;
                    to(i) = routeAllNodes(i);
                    lastNode = to(i);
                    % Incoming passengers
                    if(isempty(lastPassengers))
                        passengersIn{i} = 0;
                    else
                        passengersIn{i} = num2str(lastPassengers);
                    end
                    % Get passenger ids after node
                    passengerIds = [];
                    requestsAfterNode = routePassengersAfterNode{i};
                    for j = 1:size(requestsAfterNode,2)
                        oneRequestAfterNode = requestsAfterNode{j};
                         if(isa(oneRequestAfterNode, 'darpRequest'))
                            passengerIds(end+1) = oneRequestAfterNode.requestId;
                        end
                    end
                    if(isempty(passengerIds))
                        passengersOut{i} = "0";
                        lastPassengers = 0;
                    else
                        passengersOut{i} = num2str(passengerIds);
                        lastPassengers = passengerIds;
                    end         
                end
                dataTable = table(timeSteps, to, from, passengersIn, passengersOut);
                app.RouteTable.Data = dataTable(2:end,:);
            end
        end
        
        function returnRoute = getRouteById(app, idIn)
            for i = 1:size(app.routes,1)
                routeData = app.routes(i,:);
                if(routeData{1} == idIn)
                    returnRoute = routeData;
                    return
                end
            end
            returnRoute = -1;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Instantiate a darpGraph with the given sideLength       
            app.graph = darpSquareGridGraph(app.MapsidelengthSpinner.Value);
            % Init the image properties
            app.resolution = 2048;
            app.padding = 60;
            %Calculate the image coordinates
            app.convertToImgCoords();
            
            % Initialize the status flags
            app.requestsExist = false;
            app.vehiclesExist = false;
            app.routesExist = false;
            app.pickUpStartEdited = false;
            app.dropOffEndEdited = false;
            
            %clear highlights
            app.highlightedRequestNodes = [];
            app.highlightedVehicleNodes = [];
    
            %Update the table headers and formats
            app.TableRequest.ColumnName = {""; sprintf('Id'); sprintf('Pick-up\nnode'); sprintf('Pick-up\ntime'); sprintf('Drop-off\nnode'); sprintf('Drop-off\ntime')};
            app.TableRequest.ColumnFormat = {'logical', 'numeric','numeric','char','numeric','char'};
            app.TableVehicle.ColumnFormat = {'logical', 'numeric', 'numeric', 'numeric'};
            
            %Update the main image
            app.updateImg();
            
            %Set UI states
            app.mainUiState = 1; % 1 - configure simulation state
            app.uiState = 1;
            app.TextArea.Value = "App is ready.";
        end

        % Value changed function: MapsidelengthSpinner
        function MapsidelengthSpinnerValueChanged(app, event)
            if(app.requestsExist || app.vehiclesExist)
                question = "Requests or vehicles have been added to this simulation. By changing the sidelength all requests and depots will be deleted. \n Do you want to continue?";
                answer = questdlg(question, "Warning", "Yes", "Cancel", "Yes");
                if(answer == "Yes")
                    % Delete the requests
                    app.requests = 0;
                    app.requestsExist = false;
                    % Delete the depots
                    app.vehicles = 0;
                    app.vehiclesExist = false;
                    %Update the graph
                    app.graph = darpSquareGridGraph(app.MapsidelengthSpinner.Value);
                    %Update the image coordinates
                    app.convertToImgCoords();
                    %Update the two tables
                    app.updateRequestsTable()
                    app.updateDepotsTable();
                    %Update the main image
                    app.updateImg();
                    %Update text area
                    app.TextArea.Value = "App is ready.";
                else
                    % Reset the spinner value
                    app.MapsidelengthSpinner.Value = app.graph.sideLength;
                end
            else
                %Update the graph
                app.graph = darpSquareGridGraph(app.MapsidelengthSpinner.Value);
                %Update the image coordinates
                app.convertToImgCoords();
                %Update the main image
                app.updateImg();
                %Update text area
                app.TextArea.Value = "App is ready.";
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
           % Get the position of the image
            imgX = app.Image.Position(1);
            imgY = app.Image.Position(2);
            imgWidth = app.Image.Position(3);
            imgHeight = app.Image.Position(4);
  
            %The global coordinates of the click
            clickCoords = event.Source.CurrentPoint;
            
            % Handle rectangleness
            if(imgWidth > imgHeight)
                imgX = imgX + (imgWidth - imgHeight) / 2;
                %Check if the click occured inside the image
                if(clickCoords(1) > imgX && clickCoords(2) > imgY && ...
                    clickCoords(1) < imgX + imgHeight && ...
                    clickCoords(2) < imgY + imgHeight)
                    
                    clickXImg = floor((imgY + imgHeight - clickCoords(2))/imgHeight*app.resolution);
                    clickYImg = floor((clickCoords(1) - imgX)/imgHeight*app.resolution);
                    
                    fprintf("Img click: X,Y = %d,%d\n", clickXImg, clickYImg)
                    app.handleImgClick(clickXImg, clickYImg) 
                end
            else
                imgY = imgY + (imgHeight - imgWidth) / 2;  
                %Check if the click occured inside the image
                if(clickCoords(1) > imgX && clickCoords(2) > imgY && ...
                    clickCoords(1) < imgX + imgWidth && ...
                    clickCoords(2) < imgY + imgWidth)
                    
                    clickXImg = floor((imgY + imgWidth - clickCoords(2))/imgWidth*app.resolution);
                    clickYImg = floor((clickCoords(1) - imgX)/imgWidth*app.resolution);
                    
                    fprintf("Img click: X,Y = %d,%d\n", clickXImg, clickYImg)
                    app.handleImgClick(clickXImg, clickYImg)           
                end
            end
            
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            
            %Handle ESC button pressed when adding new requests or depots
            switch key
                case 'escape'
                    if(app.uiState == 2 || app.uiState == 3)
                        app.uiState = 1; % set STATE_ADD_REQUEST
                        app.RequestsPanel.BackgroundColor = [0.94,0.94,0.94]; %orginal grey
                        app.RequestsPanel.FontWeight = 'normal';
                        app.AddnewButton.Text = "Add new";
                        % Enable the editfields
                        app.TimewindowEditField.Editable = "on";
                        app.DropoffendEditField.Editable = "on";
                        % Redraw the top layer to remove the possibly added pickup
                        % node
                        app.updateImgTopLayer(-1)   
                    elseif(app.uiState == 4)
                        app.uiState = 1;
                        % Enable the spinner
                        app.CapacitySpinner.Enable = "on";
                        % Modify appearance
                        app.VehiclesPanel.BackgroundColor = [0.94,0.94,0.94]; %orginal grey
                        app.AddDepotButton.Text = "Add new";
                        % Switch to STATE_ADD_DEPOT
                        app.uiState = 1; 
                        app.TextArea.Value = "App is ready.";
                    end
                otherwise
                    %Other key was pressed
            end
        end

        % Value changing function: TimewindowEditField
        function TimewindowEditFieldValueChanging(app, event)
            app.TimewindowEditField.FontColor = 'black';
            app.pickUpStartEdited = true;
        end

        % Value changing function: DropoffendEditField
        function DropoffendEditFieldValueChanging(app, event)
            app.DropoffendEditField.FontColor = 'black';
            app.dropOffEndEdited = true;
        end

        % Button pushed function: AddnewButton
        function AddnewButtonPushed(app, event)
            if(app.uiState == 1)
                %If the text is incorrect
                if(validateTimeInput(app.TimewindowEditField.Value))
                    app.TimewindowEditField.Value = '00:00';
                    app.TimewindowEditField.FontColor = 'red';
                    app.pickUpStartEdited = false;
                    app.TextArea.Value = "Incorrect pickup time.";
                elseif(validateTimeInput(app.DropoffendEditField.Value))
                    app.DropoffendEditField.Value = '00:00';
                    app.DropoffendEditField.FontColor = 'red';
                    app.dropOffEndEdited = false;
                    app.TextArea.Value = "Incorrect drop off time.";
                elseif(convertTimeToMin(app.TimewindowEditField.Value) >= convertTimeToMin(app.DropoffendEditField.Value))
                    app.DropoffendEditField.Value = '00:00';
                    app.DropoffendEditField.FontColor = 'red';
                    app.dropOffEndEdited = false;
                    app.TextArea.Value = "Pickup has to be earlier than dropoff.";
                else
                    % Disable the editfields
                    app.TimewindowEditField.Editable = "off";
                    app.DropoffendEditField.Editable = "off";
                    % Modify appearance
                    app.RequestsPanel.BackgroundColor = '#9BE2C5'; %lightgreen
                    app.AddnewButton.Text = "Cancel";
                    % Switch to STATE_ADD_REQUEST
                    app.uiState = 2; 
                    app.TextArea.Value = "Select pickup node!";
                end
            elseif(app.uiState == 2 || app.uiState == 3)
                app.uiState = 1; % set STATE_BASE
                app.TextArea.Value = "App is ready.";
                app.RequestsPanel.BackgroundColor = [0.94,0.94,0.94]; %orginal grey
                app.AddnewButton.Text = "Add new";
                % Enable the editfields
                app.TimewindowEditField.Editable = "on";
                app.DropoffendEditField.Editable = "on";
                % Redraw the top layer to remove the possibly added pickup
                % node
                app.updateImgTopLayer(-1)
            end
        end

        % Button pushed function: AddDepotButton
        function AddDepotButtonPushed(app, event)
            if(app.uiState == 1)
                % Disable the spinner
                app.CapacitySpinner.Enable = "off";
                % Modify appearance
                app.VehiclesPanel.BackgroundColor = '#9BE2C5'; %lightgreen
                app.AddDepotButton.Text = "Cancel";
                % Switch to STATE_ADD_DEPOT
                app.uiState = 4; 
                app.TextArea.Value = "Select depot node!";
            elseif(app.uiState == 4)
                % Enable the spinner
                app.CapacitySpinner.Enable = "on";
                % Modify appearance
                app.VehiclesPanel.BackgroundColor = [0.94,0.94,0.94]; %orginal grey
                app.AddDepotButton.Text = "Add new";
                % Switch to STATE_ADD_DEPOT
                app.uiState = 1; 
                app.TextArea.Value = "App is ready.";
            end
        end

        % Menu selected function: NewsimulationMenu
        function NewsimulationMenuSelected(app, event)
            if(app.mainUiState == 2)
                question = "The solution will be lost. Do you want to save it?";
                answer = questdlg(question, "Warning", "Save", "Don't save", "Cancel", "Save");
                if(answer == "Save")
                    %Save solution
                    
                    % Switch to configuration state
                    app.routesExist = false;
                    app.mainUiState = 1;
                    app.TabGroup.SelectedTab = app.ConfigurationTab;
                    app.newSimulation();
                elseif(answer == "Don't save")
                    % Switch to configuration state
                    app.routesExist = false;
                    app.mainUiState = 1;
                    app.TabGroup.SelectedTab = app.ConfigurationTab;
                    app.newSimulation();
                end
            elseif(app.requestsExist || app.vehiclesExist)
                 question = "All data will be deleted. Do you want to save the current simulation?";
                 answer = questdlg(question, "Warning", "Save", "New simulation", "Cancel", "Yes");
                 if(answer == "New simulation")
                     app.newSimulation();
                 elseif(answer == "Save")
                    app.SaveSimulation();
                    app.newSimulation();
                 end
            else
                %Nothing -> simulation is already empty.
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(exist("app.warnDialog"))
                delete(app.warnDialog)
            end
            
            if(exist("app.solutionWindow"))
                delete(app.solutionWindow)
            end
            delete(app)
        end

        % Menu selected function: SavesimulationMenu
        function SavesimulationMenuSelected(app, event)
            app.saveSimulation();
        end

        % Menu selected function: LoadsimulationMenu
        function LoadsimulationMenuSelected(app, event)
            if(app.requestsExist || app.vehiclesExist)
                question = "All data will be deleted. Do you want to save the current simulation?";
                answer = questdlg(question, "Warning", "Save", "Don't save", "Cancel", "Yes");
                if(strcmp(answer,"Yes"))
                    app.saveSimulation();
                end
                
                if(~strcmp(answer,"Cancel"))
                    app.mainUiState = 1;
                    app.loadSimulation(); 
                end
            else
                app.mainUiState = 1;
                app.loadSimulation();
            end
        end

        % Button pushed function: DeleteselectedRequestButton
        function DeleteselectedRequestButtonPushed(app, event)
            if(app.requestsExist)
                tableData = get(app.TableRequest, 'data');
                rowsToDelete = tableData(tableData{:,1}==1, :);
                idsToDelete = rowsToDelete{:,2};
                %Iterate over the ids to delete
                for i = 1:size(idsToDelete)
                    id = idsToDelete(i);
                    if(size(app.requests) == 0)
                        break;
                    end
                    %Iterate over all the requests and delete only one
                    if(size(app.requests,1)>1)
                        for j = 1:size(app.requests,1)
                            if (app.requests(j).requestId == id)
                                app.requests(j) = []; 
                                break;
                            end
                        end
                    else
                        app.requests = [];
                        app.requestsExist = false;
                    end 
                end
            end
            app.updateImgTopLayer(-1);
            app.updateRequestsTable();
        end

        % Button pushed function: DeleteselectedDepotsButton
        function DeleteselectedDepotsButtonPushed(app, event)
            if(app.vehiclesExist)
                tableData = get(app.TableVehicle, 'data');
                rowsToDelete = tableData(tableData{:,1}==1, :);
                idsToDelete = rowsToDelete{:,2};
                %Iterate over the ids to delete
                for i = 1:size(idsToDelete)
                    id = idsToDelete(i);
                    %Iterate over all the requests and delete only one
                    if(size(app.vehicles,1) > 1)
                        for j = 1:size(app.vehicles,1)
                            fprintf("j: %d arr size: %d\n", j, size(app.vehicles,1))
                            if (app.vehicles(j).vehicleId == id)
                                app.vehicles(j) = []; 
                                break;
                            end
                        end
                    else
                        app.vehicles = [];
                        app.vehiclesExist = false;
                    end
                end
            end
            app.updateImgTopLayer(-1);
            app.updateDepotsTable();
        end

        % Button pushed function: HighlightselectedRequestButton
        function HighlightselectedRequestButtonPushed(app, event)
            if(app.requestsExist)
                tableData = get(app.TableRequest, 'data');
                selected = tableData(tableData{:,1}==1, :);
                if(size(selected,1)>1)
                    app.TextArea.Value = "Only one request can be highlighted.";
                    tableData{:,1} = zeros(size(tableData{:,1}));
                    app.TableRequest.Data = tableData;
                    % Clear other highlight
                    app.highlightedRequestNodes = [];
                    app.highlightedVehicleNodes = [];
                    app.updateImgTopLayer(-1);
                elseif(size(selected,1)<1)
                    %clear highlights
                    app.highlightedRequestNodes = [];
                    app.highlightedVehicleNodes = [];
                    app.updateImgTopLayer(-1);
                else
                    request = app.getRequestById(selected{1,2});
                    app.highlightedRequestNodes = [request.pickUpNodeId, request.dropOffNodeId];
                    app.highlightedVehicleNodes = [];
                    app.updateImgTopLayer(-1);
                end
            end
        end

        % Button pushed function: HighlightselectedVehicleButton
        function HighlightselectedVehicleButtonPushed(app, event)
            if(app.requestsExist)
                tableData = get(app.TableVehicle, 'data');
                selected = tableData(tableData{:,1}==1, :);
                if(size(selected,1)>1)
                    app.TextArea.Value = "Only one vehicle can be highlighted.";
                    tableData{:,1} = zeros(size(tableData{:,1}));
                    app.TableVehicle.Data = tableData;
                    %clear highlights
                    app.highlightedRequestNodes = [];
                    app.highlightedVehicleNodes = [];
                    app.updateImgTopLayer(-1);
                elseif(size(selected,1)<1)
                    %clear highlights
                    app.highlightedRequestNodes = [];
                    app.highlightedVehicleNodes = [];
                    app.updateImgTopLayer(-1);
                else
                    vehicle = app.getVehicleById(selected{1,2});
                    app.highlightedRequestNodes = [];
                    app.highlightedVehicleNodes = vehicle.depotNodeId;
                    app.updateImgTopLayer(-1);
                end
            end
        end

        % Menu selected function: StartnewsolverMenu
        function StartnewsolverMenuSelected(app, event)
            if(app.mainUiState == 1)
                if(app.requestsExist && app.vehiclesExist)
                    app.solutionWindow = solverWindow(app, app.graph, app.requests, app.vehicles, app.EdgetimeSpinner.Value);
                else
                    warndlg("No requests or nor vehicles are added to the simulation.")
                end           
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            % Check which tab is to be selected
            switch selectedTab.Title
                case 'Configuration'
                    %Configuration state
                    if(app.mainUiState == 1)
                        app.TabGroup.SelectedTab = selectedTab;
                    else
                        app.TabGroup.SelectedTab = event.OldValue;
                    end
                    
                case 'Visualization'
                    %Visualization state
                    if(app.mainUiState == 2)
                        app.TabGroup.SelectedTab = selectedTab;
                    else
                        app.TabGroup.SelectedTab = event.OldValue;
                    end
                otherwise
                    error('Unknown tab: %s.',selectedTab.Title)
            end
            
        end

        % Menu selected function: SimulationMenu
        function SimulationMenuSelected(app, event)
            if(app.mainUiState == 1)
                app.EditcurrentsimulationMenu.Enable = false;
            elseif(app.mainUiState == 2)
                app.EditcurrentsimulationMenu.Enable = true;                
            end
        end

        % Menu selected function: SolutionMenu
        function SolutionMenuSelected(app, event)
            if(app.mainUiState == 1)
                app.SavesolutionMenu.Enable = false;
                app.LoadsolutionMenu.Enable = true;
            elseif(app.mainUiState == 2)
                app.SavesolutionMenu.Enable = true;
                app.LoadsolutionMenu.Enable = false;
            end
        end

        % Menu selected function: EditcurrentsimulationMenu
        function EditcurrentsimulationMenuSelected(app, event)
            if(app.mainUiState == 2)
                question = "The solution will be lost. Do you want to save it?";
                answer = questdlg(question, "Warning", "Save", "Don't save", "Cancel", "Save");
                if(answer == "Save")
                    %Save solution
                    
                    % Switch to configuration state
                    app.routesExist = false;
                    app.mainUiState = 1;
                    app.TabGroup.SelectedTab = app.ConfigurationTab;
                elseif(answer == "Don't save")
                    % Switch to configuration state
                    app.routesExist = false;
                    app.mainUiState = 1;
                    app.TabGroup.SelectedTab = app.ConfigurationTab;
                end
            end
        end

        % Menu selected function: SavesolutionMenu
        function SavesolutionMenuSelected(app, event)
            app.saveSolution()
        end

        % Menu selected function: LoadsolutionMenu
        function LoadsolutionMenuSelected(app, event)
            status = app.loadSolution();
            if(status)
                warndlg("Solution could not be loaded. Check the input file!")
                %An error occured - solution cannot be loaded
                app.mainUiState = 1; 
                app.uiState = 1;
                app.requestsExist = false;
                app.TabGroup.SelectedTab = app.VisualizationTab;
            else
                % Update the main image
                app.updateImg();
                % Set UI statea
                app.mainUiState = 2;
                app.TabGroup.SelectedTab = app.VisualizationTab;
                % Update the dropdown menu where the vehicles can be selected
                app.updateRouteDropDown()
                % Update the table that shows the route of the selected
                % vehicle
                app.updateRouteTable()                
            end
        end

        % Value changed function: VehiclesDropDown
        function VehiclesDropDownValueChanged(app, event)
            app.updateRouteTable();
            app.updateImg();
            app.updateImgTopLayer(-1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.SimulationMenu = uimenu(app.UIFigure);
            app.SimulationMenu.MenuSelectedFcn = createCallbackFcn(app, @SimulationMenuSelected, true);
            app.SimulationMenu.Text = 'Simulation';
            app.NewsimulationMenu = uimenu(app.SimulationMenu);
            app.NewsimulationMenu.MenuSelectedFcn = createCallbackFcn(app, @NewsimulationMenuSelected, true);
            app.NewsimulationMenu.Text = 'New simulation';
            app.SavesimulationMenu = uimenu(app.SimulationMenu);
            app.SavesimulationMenu.MenuSelectedFcn = createCallbackFcn(app, @SavesimulationMenuSelected, true);
            app.SavesimulationMenu.Text = 'Save simulation';
            app.LoadsimulationMenu = uimenu(app.SimulationMenu);
            app.LoadsimulationMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadsimulationMenuSelected, true);
            app.LoadsimulationMenu.Text = 'Load simulation';
            app.EditcurrentsimulationMenu = uimenu(app.SimulationMenu);
            app.EditcurrentsimulationMenu.MenuSelectedFcn = createCallbackFcn(app, @EditcurrentsimulationMenuSelected, true);
            app.EditcurrentsimulationMenu.Text = 'Edit current simulation';
            app.SolutionMenu = uimenu(app.UIFigure);
            app.SolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @SolutionMenuSelected, true);
            app.SolutionMenu.Text = 'Solution';
            app.StartnewsolverMenu = uimenu(app.SolutionMenu);
            app.StartnewsolverMenu.MenuSelectedFcn = createCallbackFcn(app, @StartnewsolverMenuSelected, true);
            app.StartnewsolverMenu.Text = 'Start new solver';
            app.SavesolutionMenu = uimenu(app.SolutionMenu);
            app.SavesolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @SavesolutionMenuSelected, true);
            app.SavesolutionMenu.Text = 'Save solution';
            app.LoadsolutionMenu = uimenu(app.SolutionMenu);
            app.LoadsolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadsolutionMenuSelected, true);
            app.LoadsolutionMenu.Text = 'Load solution';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.ConfigGrid = uigridlayout(app.ConfigurationTab);
            app.TableRequest = uitable(app.ConfigGrid);
            app.TableRequest.ColumnName = {'Id'; 'Pick-up\nnode'; 'Pick-up\ntime'; 'Drop-off\nnode'; 'Drop-off\ntime'};
            app.TableRequest.RowName = {};
            app.TableRequest.ColumnSortable = true;
            app.TableRequest.ColumnEditable = [true false false false false false];
            app.TableVehicle = uitable(app.ConfigGrid);
            app.TableVehicle.ColumnName = {''; 'Id'; 'Node'; 'Capacity'};
            app.TableVehicle.RowName = {};
            app.TableVehicle.ColumnSortable = [true true true false];
            app.TableVehicle.ColumnEditable = [true false false false];
            app.GridLayout7 = uigridlayout(app.ConfigGrid);
            app.MapsidelengthSpinnerLabel = uilabel(app.GridLayout7);
            app.MapsidelengthSpinnerLabel.Text = 'Map side length';
            app.MapsidelengthSpinner = uispinner(app.GridLayout7);
            app.MapsidelengthSpinner.Limits = [4 10];
            app.MapsidelengthSpinner.ValueChangedFcn = createCallbackFcn(app, @MapsidelengthSpinnerValueChanged, true);
            app.MapsidelengthSpinner.Value = 6;
            app.EdgetimeSpinnerLabel = uilabel(app.GridLayout7);
            app.EdgetimeSpinnerLabel.Text = 'Edge time';
            app.EdgetimeSpinner = uispinner(app.GridLayout7);
            app.EdgetimeSpinner.Limits = [1 10];
            app.EdgetimeSpinner.Value = 1;
            app.RequestsPanel = uipanel(app.ConfigGrid);
            app.RequestsPanel.Title = 'Requests';
            app.AddnewButton = uibutton(app.RequestsPanel, 'push');
            app.AddnewButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewButtonPushed, true);
            app.AddnewButton.Text = 'Add new';
            app.TimewindowEditFieldLabel = uilabel(app.RequestsPanel);
            app.TimewindowEditFieldLabel.Text = 'Time window';
            app.TimewindowEditField = uieditfield(app.RequestsPanel, 'text');
            app.TimewindowEditField.ValueChangingFcn = createCallbackFcn(app, @TimewindowEditFieldValueChanging, true);
            app.DropoffendEditField = uieditfield(app.RequestsPanel, 'text');
            app.DropoffendEditField.ValueChangingFcn = createCallbackFcn(app, @DropoffendEditFieldValueChanging, true);
            app.DeleteselectedRequestButton = uibutton(app.RequestsPanel, 'push');
            app.DeleteselectedRequestButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedRequestButtonPushed, true);
            app.DeleteselectedRequestButton.Text = 'Delete selected';
            app.HighlightselectedRequestButton = uibutton(app.RequestsPanel, 'push');
            app.HighlightselectedRequestButton.ButtonPushedFcn = createCallbackFcn(app, @HighlightselectedRequestButtonPushed, true);
            app.HighlightselectedRequestButton.Text = 'Highlight selected';
            app.VehiclesPanel = uipanel(app.ConfigGrid);
            app.VehiclesPanel.Title = 'Vehicles';
            app.AddDepotButton = uibutton(app.VehiclesPanel, 'push');
            app.AddDepotButton.ButtonPushedFcn = createCallbackFcn(app, @AddDepotButtonPushed, true);
            app.AddDepotButton.Text = 'Add new';
            app.CapacitySpinnerLabel = uilabel(app.VehiclesPanel);
            app.CapacitySpinnerLabel.Text = 'Capacity';
            app.CapacitySpinner = uispinner(app.VehiclesPanel);
            app.CapacitySpinner.Limits = [1 10];
            app.CapacitySpinner.RoundFractionalValues = 'on';
            app.CapacitySpinner.Value = 3;
            app.DeleteselectedDepotsButton = uibutton(app.VehiclesPanel, 'push');
            app.DeleteselectedDepotsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedDepotsButtonPushed, true);
            app.DeleteselectedDepotsButton.Text = 'Delete selected';
            app.HighlightselectedVehicleButton = uibutton(app.VehiclesPanel, 'push');
            app.HighlightselectedVehicleButton.ButtonPushedFcn = createCallbackFcn(app, @HighlightselectedVehicleButtonPushed, true);
            app.HighlightselectedVehicleButton.Text = 'Highlight selected';
            app.TextArea = uitextarea(app.ConfigGrid);
            app.TextArea.Editable = 'off';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.GridLayoutVisualize = uigridlayout(app.VisualizationTab);
            app.RouteTable = uitable(app.GridLayoutVisualize);
            app.RouteTable.ColumnName = {'Timestep'; 'Arrived to'; 'From'; 'Passengers In'; 'Passengers Out'};
            app.RouteTable.RowName = {};
            app.TextArea_2 = uitextarea(app.GridLayoutVisualize);
            app.GridLayout8 = uigridlayout(app.GridLayoutVisualize);
            app.VehiclesDropDown = uidropdown(app.GridLayout8);
            app.VehiclesDropDown.ValueChangedFcn = createCallbackFcn(app, @VehiclesDropDownValueChanged, true);
            app.VehiclesLabel = uilabel(app.GridLayout8);
            app.VehiclesLabel.Text = 'Vehicles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end