classdef solverWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        GridLayout3         matlab.ui.container.GridLayout
        ShowsolutionButton  matlab.ui.control.Button
        SavesolutionButton  matlab.ui.control.Button
        GridLayout2         matlab.ui.container.GridLayout
        STARTButton         matlab.ui.control.Button
        DropDown            matlab.ui.control.DropDown
        TextArea            matlab.ui.control.TextArea
        SelectsolverLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingApp;
        myVisualizerWindow;
        
        %Simulation data
        graph;
        requests;
        vehicles;
        routes;
        edgeTime;
        
        %
        routesExist;
        unservedRequests;
    end
    
    methods (Access = private)
        
        function updateTextArea(app)
            text = "";
            if(size(app.routes,1)>0)
                for i = 1:size(app.routes,1)                  
                    line = sprintf('Route %d nodes:', app.routes{i,1});
                    nodes = app.routes{i,2};
                    requestsOnBoard = app.routes{i,4};
                    for j = 1:size(nodes,2)
                        line = sprintf('%s %d',line, nodes(j));
                        requestsOnBoardThisNode = requestsOnBoard{j};
                        firstRequest = -1;
                        if(size(requestsOnBoardThisNode,2)>0)
                            if(iscell(requestsOnBoardThisNode) )
                                firstRequest = requestsOnBoardThisNode{1};
                            else
                                firstRequest = requestsOnBoardThisNode(1);
                            end
                        end
                        
                        if(~isnumeric(firstRequest))
                            line = sprintf('%s(',line);
                            for k = 1:size(requestsOnBoardThisNode,2)
                                request = requestsOnBoardThisNode{k};
                                if(~isnumeric(request))
                                    line = sprintf('%s %d',line, request.requestId);
                                end
                            end
                            line = sprintf('%s )',line);
                        end
                    end
                    text = sprintf('%s%s\n',text,line);
                end
                
                try
                    if(size(app.unservedRequests,1) == 0)
                        text = sprintf("%s\n\nAll requests are served.\n", text);
                    else
                        text = sprintf("%s\n\nUnserved requests: ", text);
                        for i = 1:size(app.unservedRequests,1)
                            text = sprintf("%s %d",app.unservedRequests{i,1}.requestId);
                        end
                    end
                catch E
                    disp("An error occured when printing the unserevd requests")
                end
                app.TextArea.Value = text;
            else
                app.TextArea.Value = 'No routes';
            end
        end
        
        function processRoutes(app)
            % Iterete over all routes
            for i = 1:size(app.routes,1)
                % Iterate over the passengersOnBoardAfterNode array
                passengersAfterNodes = app.routes{i,4};
                for j = 1:size(passengersAfterNodes,2)
                    passengersAfterThisNode = passengersAfterNodes{1,j};
                    newRequestsAfterThisNode = {};
                    % Iterate over the array
                    for k = 1:size(passengersAfterThisNode,2)
                        if(iscell(passengersAfterThisNode))
                            request = passengersAfterThisNode{1,k};
                        else
                            request = passengersAfterThisNode(1,k);
                        end
                        
                        if(~isempty(request))
                            newRequestsAfterThisNode{end+1} = request;
                        end
                    end
                    passengersAfterNodes{1,j} = newRequestsAfterThisNode;                    
                end
                app.routes{i,4} = passengersAfterNodes;
            end
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, graphIn, requestsIn, vehiclesIn, edgeTime)
            app.callingApp = mainapp;
            
            %Store simulation data
            app.graph = graphIn;
            app.requests = requestsIn;
            app.vehicles = vehiclesIn;
            app.edgeTime = edgeTime;
           
            %Status flags
            app.routesExist = false;
            
            % Hide button
            app.ShowsolutionButton.Visible = false;
            app.SavesolutionButton.Visible = false;
                      
            %TODO Disable calling app
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if(app.DropDown.Value == "Test solution")
                app.routes = cell(size(app.vehicles,1),2);
                %
                n_nodes = 5;
                %                
                for i = 1:size(app.vehicles,1)
                    nodes = zeros(n_nodes,1);
                    node = app.vehicles(i).depotNodeId;
                    nodes(1) = node;
                    
                    % Generate random steps
                    for j = 2:n_nodes
                        %Neighbors are the nodes where the adjecency matrix
                        %is 1
                        neighbors = find(app.graph.adjMat(node,:) == 1);
                        
                        %Iterate over all the neighbors
                        for k = 1:size(neighbors,2)
                            if(~ismember(neighbors(k),nodes))
                                nodes(j) = neighbors(k);
                                node = neighbors(k);
                            end
                        end
                    end
                    app.routes(i,:) = {uint16(app.vehicles(i).vehicleId), nodes};
                end
                app.routesExist = true;    
            elseif(app.DropDown.Value == "Naive solution")
                solver = naiveSolver(app.graph, app.requests, app.vehicles, app.edgeTime);
                solver = solver.solve();
                app.routes = solver.getRoutes();
                app.requests = solver.possibleRequests;
                app.vehicles = solver.vehicles;
                app.unservedRequests = solver.getUnservedRequests();
                app.processRoutes();
                app.routesExist = true;
            end
            
            app.updateTextArea();
            app.ShowsolutionButton.Visible = true;
            app.SavesolutionButton.Visible = true;
        end

        % Button pushed function: ShowsolutionButton
        function ShowsolutionButtonPushed(app, event)
            if(app.routesExist)               
                result = 2; %Changing to visualization mode
                %Returning data to the calling app
                app.callingApp.returnFromSolver(result, app.routes, app.requests, app.vehicles)
                delete(app)
            else
                warndlg("Solution is not ready.")
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %Main uistate should be configuration mode
            result = 1; 
            %Returning data to the calling app
            app.callingApp.returnFromSolver(result, app.routes)
            %Delete this window
            delete(app)            
        end

        % Button pushed function: SavesolutionButton
        function SavesolutionButtonPushed(app, event)
            if(~app.routesExist)
                app.warnDialog = warndlg("Nothing to save here.");
            else
                %Open a UI dialog to select the path
                filename = "darpSimulationSolution-" + datestr(now, 'yyyy-mm-dd-HH-MM') + ".mat";
                [file,path] = uiputfile('*.mat', "Save simulation", filename);
                if isequal(file,0) || isequal(path,0)
                    %Nothing
                else
                    %Save the simulation data
                    sideLength_ = app.graph.sideLength;
                    requests_ = app.requests;
                    vehicles_ = app.vehicles;
                    routes_ = app.routes;
                    edgeTime_ = app.edgeTime;
                    save(fullfile(path,file),'sideLength_', 'edgeTime_', 'requests_', 'vehicles_', 'routes_');
                end     
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SelectsolverLabel = uilabel(app.GridLayout);
            app.SelectsolverLabel.Text = 'Select solver';
            app.TextArea = uitextarea(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {'Naive solution', 'Test solution'};
            app.DropDown.Value = 'Naive solution';
            app.STARTButton = uibutton(app.GridLayout2, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SavesolutionButton = uibutton(app.GridLayout3, 'push');
            app.SavesolutionButton.ButtonPushedFcn = createCallbackFcn(app, @SavesolutionButtonPushed, true);
            app.SavesolutionButton.Text = 'Save solution';
            app.ShowsolutionButton = uibutton(app.GridLayout3, 'push');
            app.ShowsolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowsolutionButtonPushed, true);
            app.ShowsolutionButton.Text = 'Show solution';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = solverWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end